classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MovementSpeedEvaluateBotGaugeLabel  matlab.ui.control.Label
        IgnitionEbotSwitchLabel        matlab.ui.control.Label
        IgnitionQBotSwitchLabel        matlab.ui.control.Label
        StopProgramButton              matlab.ui.control.Button
        MovementSpeedEvaluateBotGauge  matlab.ui.control.LinearGauge
        MovementSpeedQuenchBotGaugeLabel  matlab.ui.control.Label
        PauseProgramButton             matlab.ui.control.Button
        IgnitionEbotSwitch             matlab.ui.control.ToggleSwitch
        IgnitionQBotSwitch             matlab.ui.control.ToggleSwitch
        EmergencyButton                matlab.ui.control.StateButton
        WSlider                        matlab.ui.control.Slider
        WSliderLabel                   matlab.ui.control.Label
        MovementSpeedQuenchBotGauge    matlab.ui.control.LinearGauge
        StartProgramButton             matlab.ui.control.Button
        VSlider                        matlab.ui.control.Slider
        VSliderLabel                   matlab.ui.control.Label
        OperatingLamp                  matlab.ui.control.Lamp
        OperatingLampLabel             matlab.ui.control.Label
        USlider                        matlab.ui.control.Slider
        USliderLabel                   matlab.ui.control.Label
        IdleLamp                       matlab.ui.control.Lamp
        IdleLampLabel                  matlab.ui.control.Label
        ProgramSelectButtonGroup       matlab.ui.container.ButtonGroup
        DisposeButton                  matlab.ui.control.ToggleButton
        EvaluateButton                 matlab.ui.control.ToggleButton
        QuenchButton                   matlab.ui.control.ToggleButton
        PickupButton                   matlab.ui.control.ToggleButton
        MainButton                     matlab.ui.control.ToggleButton
        ZSlider                        matlab.ui.control.Slider
        ZSliderLabel                   matlab.ui.control.Label
        YSlider                        matlab.ui.control.Slider
        YSliderLabel                   matlab.ui.control.Label
        StoppedLamp                    matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        AxisControlButtonGroup         matlab.ui.container.ButtonGroup
        EvaluateBotButton              matlab.ui.control.RadioButton
        QuenchBotButton                matlab.ui.control.RadioButton
        XSlider                        matlab.ui.control.Slider
        XSliderLabel                   matlab.ui.control.Label
    end


    properties (Access = private)
        CurrentRobot  % Stores the currently selected robot (Excalibot or UR5)
        robot_excalibot %Excalibot class handler
        robot_UR %UR robot class handler
        robot_UR_maxLimits
        robot_UR_minLimits
        robot_excalibot_maxLimits
        robot_excalibot_minLimits
    end

    methods (Access = public)

        % Fetch current robot position and update sliders
        function updateSliders(app, robot)
            % Get the current position and rotation of the robot's end effector
            currentPosition = transl(robot.model.fkine(robot.model.getpos()));  % Get translation (x, y, z)
            currentRPY = tr2rpy(robot.model.fkine(robot.model.getpos()));       % Get rotation (roll, pitch, yaw)

            % Update sliders with current position
            app.XSlider.Value = currentPosition(1);  % X-axis
            app.YSlider.Value = currentPosition(2);  % Y-axis
            app.ZSlider.Value = currentPosition(3);  % Z-axis
            app.USlider.Value = rad2deg(currentRPY(1));  % Roll
            app.VSlider.Value = rad2deg(currentRPY(2));  % Pitch
            app.WSlider.Value = rad2deg(currentRPY(3));  % Yaw
        end

        % Function to move the robot based on slider values
        function moveRobotBasedOnSliders(app, robot)

          % Fetch current slider values for position (X, Y, Z) and rotation (U, V, W)
            newPosition = [app.XSlider.Value, app.YSlider.Value, app.ZSlider.Value];
            newRotation = deg2rad([app.USlider.Value, app.VSlider.Value, app.WSlider.Value]);

            moveRobot(app, robot, newPosition, newRotation, 100);
        end

        function moveRobot(app, robot, desiredPosition, desiredRotation, steps)
            checkForBoundries = true;
            graphTrajectory = true; 
            graphBoundryBox = true;
            checkForCollision = false;

            if robot == app.robot_excalibot
                ignition = app.IgnitionQBotSwitch;
            elseif robot == app.robot_UR
                ignition = app.IgnitionEbotSwitch;
            end

            if ignition.Value == "On" && app.EmergencyButton.Value == false
                [finalJointAngles, finalPosition, finalRotation, positionError, rotationError]  = calculateMotion(app, robot, desiredPosition, desiredRotation);
                disp("Desired Position");
                disp(desiredPosition);
                disp("Final Position");
                disp(finalPosition);
                disp("Position Error");
                disp(positionError);

                trajectory = calculateTrajectory(app, robot, finalJointAngles, steps, checkForBoundries, graphTrajectory, graphBoundryBox, checkForCollision);
                animateMotion(app, robot, trajectory, ignition);
            end

            %Ending Operation
            updateSliders(app, robot);
            updateLights(app);
        end

        function [jointAngles, finalPosition, finalRotation, positionError, rotationError] = calculateMotion(app, robot, desiredPosition, desiredRotation)
            % Parameters
            maxIterations = 100;    % Maximum number of iterations to try for collision-free configuration
            adjustmentStep = 0.01;  % Step size for position adjustment when collision is detected
            enableCollisionDetect = false;

            % Compute the initial transformation matrix from the desired position and rotation
            newTransformation = transl(desiredPosition) * rpy2tr(desiredRotation);
            correctionPosition = desiredPosition;

            % Initialize jointAngles and flag for collision detection
            collisionDetected = true;
            iteration = 0;

            % Iterate to find collision-free configuration
            while collisionDetected && iteration < maxIterations
                % Inverse kinematics with tolerance options
                jointAngles = robot.model.ikcon(newTransformation, 'TolFun', 1e-6, 'TolX', 1e-6);

                % Optionally refine the solution using the IK result
                jointAngles = robot.model.ikcon(newTransformation, jointAngles);

                % Check for collisions
                if enableCollisionDetect
                collisionDetected = checkCollision(app, robot, jointAngles, createEnvironmentModel(app));
                else
                    collisionDetected = false;
                end
                if collisionDetected
                    % If a collision is detected, adjust the desired position slightly
                    % You can adjust this logic as per your needs. Here we adjust in X-direction.
                    correctionPosition = correctionPosition + [adjustmentStep, 0, 0];

                    % Update the transformation matrix with the adjusted position
                    newTransformation = transl(correctionPosition) * rpy2tr(desiredRotation);
                end

                % Increment the iteration count
                iteration = iteration + 1;
            end

            if iteration == maxIterations
                warning('Unable to find a collision-free configuration within the iteration limit.');
            end

            % Forward kinematics to calculate the resulting end-effector pose
            endEffectorPose = robot.model.fkine(jointAngles);

            % Extract final position and rotation
            finalPosition = transl(endEffectorPose);
            finalRotation = tr2rpy(endEffectorPose);

            % Calculate position error
            positionError = finalPosition - desiredPosition;

            % Calculate rotational error in quaternion form
            endEffectorMatrix = endEffectorPose.T;  % Extract the 4x4 homogeneous transformation matrix
            rotationMatrix = endEffectorMatrix(1:3, 1:3);

            % Convert the rotation matrix to quaternion
            finalQuat = rotm2quat(rotationMatrix);
            desiredQuat = eul2quat(desiredRotation);

            % Compute the quaternion inverse manually
            desiredQuatInv = [desiredQuat(1), -desiredQuat(2), -desiredQuat(3), -desiredQuat(4)];

            % Calculate quaternion multiplication (finalQuat * desiredQuatInv)
            rotationError = quatmultiplyManual(app, finalQuat, desiredQuatInv);

            % Optionally, add error magnitude or further fine-tuning steps here
        end


        function trajectory = calculateTrajectory(app, robot, finalJointAngles, steps, checkForBoundries, graphTrajectory, graphBoundryBox, checkForCollision)
            % Create Trajectory for the movemement
            trajectory = jtraj(robot.model.getpos(),finalJointAngles, steps);

            if checkForBoundries == true

                % Set the required clearances
                endEffector_clearance_x = 0;
                endEffector_clearance_y = 0;
                endEffector_clearance_z = 0;

                endEffector_offset_x = 0;
                endEffector_offset_y = 0;
                endEffector_offset_z = 0;

                joint_clearance_x = 0;
                joint_clearance_y = 0;
                joint_clearance_z = 0;

                joint_offset_x = 0;
                joint_offset_y = 0;
                joint_offset_z = 0;

                if robot == app.robot_excalibot

                    %Clearance and offset for excalibot
                    endEffector_clearance_x = 0.2;
                    endEffector_clearance_y = 0.2;
                    endEffector_clearance_z = 0.15;

                    endEffector_offset_x = -0.01;
                    endEffector_offset_y = -0.05;
                    endEffector_offset_z = 0.1;

                    joint_clearance_x = 0.2;
                    joint_clearance_y = 0.2;
                    joint_clearance_z = 0.1;

                    joint_offset_x = 0;
                    joint_offset_y = -0.05;
                    joint_offset_z = 0.2;

                elseif robot == app.robot_UR

                    %Clearance and offset for UR5
                    endEffector_clearance_x = 0.05;
                    endEffector_clearance_y = 0.05;
                    endEffector_clearance_z = 0.03;

                    endEffector_offset_x = 0.01;
                    endEffector_offset_y = 0.01;
                    endEffector_offset_z = 0;

                    joint_clearance_x = 0.09;
                    joint_clearance_y = 0.09;
                    joint_clearance_z = 0.03;

                    joint_offset_x = 0;
                    joint_offset_y = 0;
                    joint_offset_z = 0.05;
                end

                % Calculate all end effector positions
                % Initialize a cell array to store end-effector poses (transformation matrices)
                endEffectorPoses = cell(size(trajectory, 1), 1);
                endEffectorPositions = cell(size(trajectory, 1), 1);
                endEffectorRotations = cell(size(trajectory, 1), 1);

                % Initialize arrays to store joint positions throughout the trajectory
                numJoints = length(robot.model.links);
                jointPositions = zeros(size(trajectory, 1), numJoints + 1, 3); % [step, joint+base, x/y/z]
                
                for i = 1:size(trajectory, 1)
                    % get current joint state
                    q = trajectory(i, :);

                    % Extrack coordinates of end effector
                    endEffectorPose = robot.model.fkine(q);
                    endEffectorPosition = transl(endEffectorPose);
                    endEffectorRotation = tr2rpy(endEffectorPose);
                    
                    
                    % Calculate the transformation matrix for each joint at step i
                    T = robot.model.base; % Start with the base transformation
                    jointPositions(i, 1, :) = T.t; % Store base position

                    for j = 1:numJoints 
                        T = T * robot.model.A(j, q); % Multiply by the transformation of each joint
                        jointPositions(i, j+1, :) = T.t; % Store the position of the joint
                    end
                    
                   
                    % save the coordinates of end effector
                    endEffectorPoses{i} = endEffectorPose;
                    endEffectorPositions{i} = endEffectorPosition;
                    endEffectorRotations{i} = endEffectorRotation;
                    
                    % Check for collisions and adjust trajectory if necessary
                    if checkForCollision
                        collisionDetected = checkCollision(app, robot, q, createEnvironmentModel(app));
                        if collisionDetected
                            disp("COLLISON")
                            q = adjustTrajectory(app, q, robot, createEnvironmentModel(app));  % Adjust joint configuration to avoid collision
                            trajectory(i, :) = q;  % Update trajectory with the new configuration
                        end
                    end
                end

                % Extract end effector x, y, z
                xPos = zeros(size(endEffectorPositions, 1), 1);
                yPos = zeros(size(endEffectorPositions, 1), 1);
                zPos = zeros(size(endEffectorPositions, 1), 1);
                for i = 1:size(endEffectorPositions, 1)
                    position = endEffectorPositions(i);
                    xyzPos = position{1};
                    xPos(i) = xyzPos(1);
                    yPos(i) = xyzPos(2);
                    zPos(i) = xyzPos(3);
                end

                % Find the minimum and maximum values for x, y, and z end effector positions
                minX_endEffector = (min(xPos) - endEffector_clearance_x) + endEffector_offset_x;
                maxX_endEffector = (max(xPos) + endEffector_clearance_x) + endEffector_offset_x;

                minY_endEffector = (min(yPos) - endEffector_clearance_y) + endEffector_offset_y;
                maxY_endEffector = (max(yPos) + endEffector_clearance_y) + endEffector_offset_y;

                minZ_endEffector = (min(zPos) - endEffector_clearance_z) + endEffector_offset_z;
                maxZ_endEffector = (max(zPos) + endEffector_clearance_z) + endEffector_offset_z;

                % find the minimum and maximum values for x, y, and z for
                % total robot
                minX_total = (min(jointPositions(:, :, 1), [], 'all') - joint_clearance_x) + joint_offset_x;
                maxX_total = (max(jointPositions(:, :, 1), [], 'all') + joint_clearance_x) + joint_offset_x;

                minY_total = (min(jointPositions(:, :, 2), [], 'all') - joint_clearance_y) + joint_offset_y;
                maxY_total = (max(jointPositions(:, :, 2), [], 'all') + joint_clearance_y) + joint_offset_y;

                minZ_total = (min(jointPositions(:, :, 3), [], 'all') - joint_clearance_z) + joint_offset_z;
                maxZ_total = (max(jointPositions(:, :, 3), [], 'all') + joint_clearance_z) + joint_offset_z;

                % Define persistent variables to store plot handles
                persistent trajHandle boxHandles totalBoundryHandles

                % Check and delete the previous trajectory and bounding box if they exist
                if ~isempty(trajHandle)
                    delete(trajHandle);  % Delete the old trajectory plot
                end
                if ~isempty(boxHandles)
                    delete(boxHandles);  % Delete the old end effector bounding box plot (both corners and edges)
                end
                if ~isempty(totalBoundryHandles)
                    delete(totalBoundryHandles);  % Delete the old total bounding box plot (both corners and edges)
                end

                % Plot the new bounding box if needed
                if graphBoundryBox == true

                    % Define the 8 corners of the total bounding box
                    corners_total = [
                        minX_total, minY_total, minZ_total;  % Corner 1 (min x, min y, min z)
                        maxX_total, minY_total, minZ_total;  % Corner 2 (max x, min y, min z)
                        maxX_total, maxY_total, minZ_total;  % Corner 3 (max x, max y, min z)
                        minX_total, maxY_total, minZ_total;  % Corner 4 (min x, max y, min z)
                        minX_total, minY_total, maxZ_total;  % Corner 5 (min x, min y, max z)
                        maxX_total, minY_total, maxZ_total;  % Corner 6 (max x, min y, max z)
                        maxX_total, maxY_total, maxZ_total;  % Corner 7 (max x, max y, max z)
                        minX_total, maxY_total, maxZ_total   % Corner 8 (min x, max y, max z)
                        ];

                    % Define the 8 corners of the end effector bounding box
                    corners_endEffector = [
                        minX_endEffector, minY_endEffector, minZ_endEffector;  % Corner 1 (min x, min y, min z)
                        maxX_endEffector, minY_endEffector, minZ_endEffector;  % Corner 2 (max x, min y, min z)
                        maxX_endEffector, maxY_endEffector, minZ_endEffector;  % Corner 3 (max x, max y, min z)
                        minX_endEffector, maxY_endEffector, minZ_endEffector;  % Corner 4 (min x, max y, min z)
                        minX_endEffector, minY_endEffector, maxZ_endEffector;  % Corner 5 (min x, min y, max z)
                        maxX_endEffector, minY_endEffector, maxZ_endEffector;  % Corner 6 (max x, min y, max z)
                        maxX_endEffector, maxY_endEffector, maxZ_endEffector;  % Corner 7 (max x, max y, max z)
                        minX_endEffector, maxY_endEffector, maxZ_endEffector   % Corner 8 (min x, max y, max z)
                        ];

                    % Define the edges of the box (each row corresponds to a pair of corners to connect)
                    edges = [
                        1, 2;  % Connect corner 1 to 2
                        2, 3;  % Connect corner 2 to 3
                        3, 4;  % Connect corner 3 to 4
                        4, 1;  % Connect corner 4 to 1 (base of the box)
                        5, 6;  % Connect corner 5 to 6
                        6, 7;  % Connect corner 6 to 7
                        7, 8;  % Connect corner 7 to 8
                        8, 5;  % Connect corner 8 to 5 (top of the box)
                        1, 5;  % Connect corner 1 to 5
                        2, 6;  % Connect corner 2 to 6
                        3, 7;  % Connect corner 3 to 7
                        4, 8   % Connect corner 4 to 8
                        ];


                    % Plot the corners of the total bounding box
                    totalBoundryHandles = gobjects(size(corners_total, 1) + size(edges, 1), 1);  % Preallocate handles
                    totalBoundryHandles(1:size(corners_total, 1)) = plot3(corners_total(:, 1), corners_total(:, 2), corners_total(:, 3), ...
                        'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'y');  % Red markers for corners
                    hold on;

                    % Plot the edges of the total box and store the handles
                    for i = 1:size(edges, 1)
                        p1 = corners_total(edges(i, 1), :);  % First point of the edge
                        p2 = corners_total(edges(i, 2), :);  % Second point of the edge
                        totalBoundryHandles(size(corners_total, 1) + i) = line([p1(1), p2(1)], [p1(2), p2(2)], [p1(3), p2(3)], ...
                            'Color', 'y', 'LineWidth', 2);  % Plot the edge as a blue line
                    end

                    % Plot the corners of the end effector bounding box
                    boxHandles = gobjects(size(corners_endEffector, 1) + size(edges, 1), 1);  % Preallocate handles
                    boxHandles(1:size(corners_endEffector, 1)) = plot3(corners_endEffector(:, 1), corners_endEffector(:, 2), corners_endEffector(:, 3), ...
                        'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'b');  % Red markers for corners
                    hold on;

                    % Plot the edges of the end effector box and store the handles
                    for i = 1:size(edges, 1)
                        p1 = corners_endEffector(edges(i, 1), :);  % First point of the edge
                        p2 = corners_endEffector(edges(i, 2), :);  % Second point of the edge
                        boxHandles(size(corners_endEffector, 1) + i) = line([p1(1), p2(1)], [p1(2), p2(2)], [p1(3), p2(3)], ...
                            'Color', 'b', 'LineWidth', 2);  % Plot the edge as a blue line
                    end
                end

                % Plot the new trajectory if needed
                if graphTrajectory == true
                    % Plot the new trajectory and store its handle
                    for i = 1:length(yPos)
                        if yPos(i) <= 1.15 && xPos(i) <= 1.25 && yPos(i) >= -1.35 && xPos(i) >= -2.25
                            % Plot points with y < 1.2 in green
                            trajHandle = [trajHandle, plot3(xPos(i), yPos(i), zPos(i), 'go', 'LineWidth', 2, 'MarkerSize', 5)];
                        else
                            % Plot points with y >= 1.2 in red
                            trajHandle = [trajHandle, plot3(xPos(i), yPos(i), zPos(i), 'ro', 'LineWidth', 2, 'MarkerSize', 5)];
                        end
                    end
                    hold on;
                end
            end
        end

        function animateMotion(app, robot, trajectory, ignition)
            app.OperatingLamp.Enable = "on";
            app.IdleLamp.Enable = "off";
            app.StoppedLamp.Enable ="off";

            % Animate motion
            for i = 1:size(trajectory, 1)
                endEffectorPose = robot.model.fkine(trajectory(i, :));
                endEffectorPosition = transl(endEffectorPose);

                if ~(endEffectorPosition(2) < 1.1 && endEffectorPosition(1) < 1.2 && endEffectorPosition(2) > -1.3 && endEffectorPosition(1) > -2.2)
                    break
                end
                if ~(ignition.Value == "On" && app.EmergencyButton.Value == false)
                    break
                end
                robot.model.animate(trajectory(i, :));
                updateSliders(app, robot);
                drawnow();
            end
            updateSliders(app, robot);
            drawnow();
        end

        function [reachablePoints, accuratePoints, minXYZ, maxXYZ, accurateMinXYZ, accurateMaxXYZ] = findReachablePoints(app, robot, xRange, yRange, zRange, resolution)
            % Initialize arrays to store reachable points
            reachablePoints = [];
            accuratePoints = [];

            % Create a mesh grid of points in the specified ranges
            [X, Y, Z] = meshgrid(xRange(1):resolution:xRange(2), ...
                yRange(1):resolution:yRange(2), ...
                zRange(1):resolution:zRange(2));

            % Iterate through the grid of points
            for i = 1:numel(X)
                % Define current desired position
                desiredPosition = [X(i), Y(i), Z(i)];
                disp(desiredPosition);

                % Set the desired rotation to zero (neutral orientation), you can modify this as needed
                desiredRotation = [0, 0, 0];  % roll, pitch, yaw


                [jointAngles, finalPosition, finalRotation, positionError, rotationError] = calculateMotion(app, robot, desiredPosition, desiredRotation);

                % If the position error is small, consider the point reachable
                reachablePoints = [reachablePoints; finalPosition];  % Add the reachable point

                if positionError < 0.01
                    accuratePoints = [accuratePoints; finalPosition];  % Add the reachable point
                end


            end

            % Calculate the minimum and maximum x, y, z coordinates of the reachable points
            if ~isempty(reachablePoints)
                minXYZ = min(reachablePoints, [], 1);  % [minX, minY, minZ]
                maxXYZ = max(reachablePoints, [], 1);  % [maxX, maxY, maxZ]
            else
                % If no points are reachable, return empty arrays
                minXYZ = [];
                maxXYZ = [];
            end

            if ~isempty(accuratePoints)
                accurateMinXYZ = min(accuratePoints, [], 1);  % [minX, minY, minZ]
                accurateMaxXYZ = max(accuratePoints, [], 1);  % [maxX, maxY, maxZ]
            else
                % If no points are reachable, return empty arrays
                accurateMinXYZ = [];
                accurateMaxXYZ = [];
            end
        end

        function result = quatmultiplyManual(app, q1, q2)
            % Quaternion multiplication: q1 * q2
            w1 = q1(1); x1 = q1(2); y1 = q1(3); z1 = q1(4);
            w2 = q2(1); x2 = q2(2); y2 = q2(3); z2 = q2(4);

            % Quaternion multiplication formula
            result = [
                w1*w2 - x1*x2 - y1*y2 - z1*z2, ...
                w1*x2 + x1*w2 + y1*z2 - z1*y2, ...
                w1*y2 + y1*w2 + z1*x2 - x1*z2, ...
                w1*z2 + z1*w2 + x1*y2 - y1*x2
                ];
        end

        function collisionDetected = checkCollision(app, robot, q, environmentModel)
            % Get number of joints
            numJoints = length(robot.model.links);

            % Initialize jointPositions to store positions of the base and all joints
            jointPositions = zeros(numJoints + 1, 3);  % [joint, x/y/z]

            % Get the base position
            T = robot.model.base;  % Start with the base transformation
            jointPositions(1, :) = T.t';  % Store the base position

            % Compute joint positions using forward kinematics
            for j = 1:numJoints
                T = T * robot.model.A(j, q);  % Multiply by the transformation of each joint
                jointPositions(j+1, :) = T.t';  % Store the position of each joint
            end

            % Define a safe distance threshold for collision avoidance
            safeDistance = 0.1; % Adjust as necessary

            % Check for collision at each joint
            collisionDetected = false;
            for j = 1:(numJoints + 1)  % Iterate over each joint position
                jointPosition = jointPositions(j, :);  % Current joint position

                % Check if any points in the environment are within the safe distance
                for i = 1:size(environmentModel, 1)
                    % Calculate the distance between the joint and environment points
                    distance = norm(environmentModel(i, :) - jointPosition);
                    if distance < safeDistance
                        % Collision detected, print the XYZ coordinates of the joint
                        fprintf('Collision detected at joint %d at coordinates: X = %.3f, Y = %.3f, Z = %.3f\n', ...
                            j, jointPosition(1), jointPosition(2), jointPosition(3));

                        % Print the coordinates of the environment point involved in the collision
                        fprintf('Environment point causing collision: X = %.3f, Y = %.3f, Z = %.3f\n', ...
                            environmentModel(i, 1), environmentModel(i, 2), environmentModel(i, 3));

                        collisionDetected = true;
                        return;  % Exit if collision is detected
                    end
                end
            end
        end


        function newQ = adjustTrajectory(app, q, robot, environmentModel)
            % Adjust the joint angles slightly to avoid collision
            adjustmentStep = 0.05; % Small step to move away from the collision
            newQ = q;

            % For each joint, attempt a small adjustment
            for i = 1:length(q)
                tempQ = newQ;
                tempQ(i) = tempQ(i) + adjustmentStep;

                % Check if the new configuration causes a collision
                if ~checkCollision(app, robot, tempQ, environmentModel)
                    newQ(i) = tempQ(i);  % Update to the new configuration if no collision
                end
            end
        end

        function environmentModel = createEnvironmentModel(app)
            % Initialize an empty array for storing environment points
            environmentModel = [];

            % Load and transform the floor
            [floorFaces, floorVertices, ~] = plyread('Ground.ply', 'tri');
            floorPos = transl(0, 0, 0);  % Example position for the floor
            floorVerticesTransformed = [floorVertices, ones(size(floorVertices, 1), 1)] * floorPos';
            environmentModel = [environmentModel; floorVerticesTransformed(:, 1:3)];

            % Load and transform the fence
            [fenceFaces, fenceVertices, ~] = plyread('Fencing.ply', 'tri');
            fencePos = transl(0, 0, 0);  % Example position for the fence
            fenceVerticesTransformed = [fenceVertices, ones(size(fenceVertices, 1), 1)] * fencePos';
            environmentModel = [environmentModel; fenceVerticesTransformed(:, 1:3)];

            % Load and transform the sword
            [swordFaces, swordVertices, ~] = plyread('Sword.ply', 'tri');
            swordSpawnPosition = transl(1.1275, 0.51221, 0.63283);  % Translation matrix for sword position
            swordVerticesTransformed = [swordVertices, ones(size(swordVertices, 1), 1)] * swordSpawnPosition';
            environmentModel = [environmentModel; swordVerticesTransformed(:, 1:3)];

            % Load and transform the cobot pedestal
            [cobotPedestalFaces, cobotPedestalVertices, ~] = plyread('URRobotPedestal.ply', 'tri');
            cobotPedestalPos = transl(0, 0, 0);  % Example position for the cobot pedestal
            cobotPedestalVerticesTransformed = [cobotPedestalVertices, ones(size(cobotPedestalVertices, 1), 1)] * cobotPedestalPos';
            environmentModel = [environmentModel; cobotPedestalVerticesTransformed(:, 1:3)];

            % Load and transform the water bucket
            [waterFaces, waterVertices, ~] = plyread('WaterBucket.ply', 'tri');
            waterPos = transl(0.15, 0, 0);  % Example position for the water bucket
            waterVerticesTransformed = [waterVertices, ones(size(waterVertices, 1), 1)] * waterPos';
            environmentModel = [environmentModel; waterVerticesTransformed(:, 1:3)];

            % Load and transform the furnace
            [furnaceFaces, furnaceVertices, ~] = plyread('Furnace.ply', 'tri');
            furnacePos = transl(0, 0, 0);  % Example position for the furnace
            furnaceVerticesTransformed = [furnaceVertices, ones(size(furnaceVertices, 1), 1)] * furnacePos';
            environmentModel = [environmentModel; furnaceVerticesTransformed(:, 1:3)];

            % Return the environment model containing the transformed vertices
        end


    end

    methods (Access = private)

        function updateLights(app)
            if app.EmergencyButton.Value == true
                app.OperatingLamp.Enable = "off";
                app.IdleLamp.Enable = "off";
                app.StoppedLamp.Enable ="on";
            else
                %Adjust Lamps QBot
                if app.IgnitionQBotSwitch.Value == "On"
                    if app.CurrentRobot == app.robot_excalibot
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "on";
                        app.StoppedLamp.Enable ="off";
                    end
                end

                if app.IgnitionQBotSwitch.Value == "Off"
                    if app.CurrentRobot == app.robot_excalibot
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "off";
                        app.StoppedLamp.Enable ="on";
                    end
                end
                %Adjust Lamps EBot
                if app.IgnitionEbotSwitch.Value == "On"
                    if app.CurrentRobot == app.robot_UR
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "on";
                        app.StoppedLamp.Enable ="off";
                    end
                end

                if app.IgnitionEbotSwitch.Value == "Off"
                    if app.CurrentRobot == app.robot_UR
                        app.OperatingLamp.Enable = "off";
                        app.IdleLamp.Enable = "off";
                        app.StoppedLamp.Enable ="on";
                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%% System Start    %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            startup_rvc;
            clc; close all;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%   Initialize Environment  %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Environment;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%  Excalibot   %%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%Select Starting Position Excalibot%%%%%%%%%
            % translation part
            position_Excalibot = [-0.06748, 0.3819, 0]; % x, y, z coordinates

            % rotation part
            roll_Excalibot = deg2rad(0); % Roll (rotation about x-axis)
            pitch_Excalibot = deg2rad(0); % Pitch (rotation about y-axis)
            yaw_Excalibot = deg2rad(180); % Yaw (rotation about z-axis)

            % transformation matrix using transl and rpy2tr
            baseTr_Excalibot = transl(position_Excalibot) * rpy2tr(roll_Excalibot, pitch_Excalibot, yaw_Excalibot);

            %%%%%%%%%% Create an instance of the Excalibot class %%%%%%%%
            app.robot_excalibot = Excalibot(baseTr_Excalibot);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%    UR  %%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%Select Starting Position UR%%%%%%%%%
            % translation part
            position_UR = [-1.3134, -0.61995, 0.58111]; % x, y, z coordinates

            % rotation part
            roll_UR = deg2rad(0); % Roll (rotation about x-axis)
            pitch_UR = deg2rad(0); % Pitch (rotation about y-axis)
            yaw_UR = deg2rad(0); % Yaw (rotation about z-axis)

            % transformation matrix using transl and rpy2tr
            baseTr_UR = transl(position_UR) * rpy2tr(roll_UR, pitch_UR, yaw_UR);

            %%%%%%%%%% Create an instance of the URx class %%%%%%%%
            app.robot_UR = UR5(baseTr_UR);

            %%%%Excalibot starting pose %%%%%
            [startintgJointAngleExcalibot, ~, ~, ~, ~] = calculateMotion(app, app.robot_excalibot, [0.7788,0.2714,1.3423], [0,0,0]);
            app.robot_excalibot.model.animate(startintgJointAngleExcalibot);
            drawnow();

            %%Ur5 starting position%%
            [startintgJointAngleUR, ~, ~, ~, ~] = calculateMotion(app, app.robot_UR, [-1.4820,-0.6836,1.5690], [0,0,0]);
            app.robot_UR.model.animate(startintgJointAngleUR);
            drawnow();

            %%%%%%%%Initialize slider control%%%%%%%%%%
            % Set initial robot as Excalibot
            app.CurrentRobot = app.robot_excalibot;

            % Initialize sliders with Excalibot's position
            updateSliders(app, app.CurrentRobot);

            %%%%Initialize Lights%%%%%%
            updateLights(app);

            %%%% Find reachable points for robots %%%%%
            findPoints = false;
            plotReachable = false;
            if findPoints
                % Define the workspace boundaries and resolution
                xRange = [-5, 5];  % X limits in meters
                yRange = [-5, 5];  % Y limits in meters
                zRange = [0.1, 5];   % Z limits in meters
                resolution = 0.2;  % 10 cm resolution

                % Call the function to find reachable Exclaibot points
                [reachablePointsExcalibot,accuratePointsExcalibot, app.robot_excalibot_minLimits, app.robot_excalibot_maxLimits,accurateMinXYZexcalibot,accurateMaxXYZexcalibot] = findReachablePoints(app, app.robot_excalibot, xRange, yRange, zRange, resolution);

                if plotReachable
                    if isempty(reachablePointsExcalibot)
                        disp('No reachable points to plot.');
                        return;
                    end

                    % Use alphaShape to create a mesh representing the volume of reachable points
                    shpExcalibot = alphaShape(reachablePointsExcalibot(:,1), reachablePointsExcalibot(:,2), reachablePointsExcalibot(:,3), resolution); % 1 is the alpha radius, adjust as needed
                    accurateShpExcalibot = alphaShape(accuratePointsExcalibot(:,1), accuratePointsExcalibot(:,2), accuratePointsExcalibot(:,3), resolution);

                    % Plot the alpha shape
                    shpExcalibotplot = plot(shpExcalibot);
                    accurateShpExcalibotPlot = plot(accurateShpExcalibot);
                    % Set the color and transparency properties
                    shpExcalibotplot.FaceColor = [0.8, 0.4, 0.4];     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
                    shpExcalibotplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
                    shpExcalibotplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)
                    
                    % Set the color and transparency properties
                    accurateShpExcalibotPlot.FaceColor = 'red';     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
                    accurateShpExcalibotPlot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
                    accurateShpExcalibotPlot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)
                end

                xRange = [-5, 5];  % X limits in meters
                yRange = [-5, 5];  % Y limits in meters
                zRange = [0.1, 5];   % Z limits in meters
                resolution = 0.2;  % 5 cm resolution

                % Call the function to find reachable URponts
                [reachablePointsUR,accuratePointsUR, app.robot_UR_minLimits, app.robot_UR_maxLimits,accurateMinXYZur,accurateMaxXYZur] = findReachablePoints(app, app.robot_UR, xRange, yRange, zRange, resolution);

                if plotReachable
                    if isempty(reachablePointsUR)
                        disp('No reachable points to plot.');
                        return;
                    end

                    % Use alphaShape to create a mesh representing the volume of reachable points
                    shpUR = alphaShape(reachablePointsUR(:,1), reachablePointsUR(:,2), reachablePointsUR(:,3), resolution); % 1 is the alpha radius, adjust as needed
                    accurateShpUR = alphaShape(accuratePointsUR(:,1), accuratePointsUR(:,2), accuratePointsUR(:,3), resolution); % 1 is the alpha radius, adjust as needed

                    % Plot the alpha shape
                    shpURplot = plot(shpUR);
                    accurateShpURplot = plot(accurateShpUR);
                    % Set the color and transparency properties
                    shpURplot.FaceColor = [0.4, 0.7, 0.4];     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
                    shpURplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
                    shpURplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)

                    % Set the color and transparency properties
                    accurateShpURplot.FaceColor = 'green';     % Change 'red' to any color you like or use an RGB triplet, e.g., [0.5, 0.8, 0.2]
                    accurateShpURplot.EdgeColor = 'none';    % 'none' hides the edges, or you can set it to a specific color
                    accurateShpURplot.FaceAlpha = 0.5;       % Set transparency (0 = fully transparent, 1 = fully opaque)

                end

                disp("MinExcalibot");
                disp(app.robot_excalibot_minLimits);

                disp ("MaxExcalibot");
                disp(app.robot_excalibot_maxLimits);

                disp("AccurateMinExcalibot");
                disp(accurateMinXYZexcalibot);

                disp ("AccurateMaxExcalibot");
                disp(accurateMaxXYZexcalibot);

                disp("MinUR");
                disp(app.robot_UR_minLimits);

                disp ("MaxUR");
                disp(app.robot_UR_maxLimits);

                disp("AccurateMinUR");
                disp(accurateMinXYZur);

                disp ("AccurateMaxUR");
                disp(accurateMaxXYZur);

                save("reachablePointsExcalibot_far.mat","reachablePointsExcalibot");
                save("reachablePointsUR_far.mat","reachablePointsUR");
                save("AccuratePointsExcalibot_far.mat","accuratePointsExcalibot");
                save("AccuratePointsUR_far.mat","accuratePointsUR");
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clc; close all;
        end

        % Value changed function: IgnitionQBotSwitch
        function IgnitionQBotSwitchValueChanged(app, event)
            updateLights(app);
        end

        % Value changed function: IgnitionEbotSwitch
        function IgnitionEbotSwitchValueChanged(app, event)
            updateLights(app);
        end

        % Selection changed function: AxisControlButtonGroup
        function AxisControlButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxisControlButtonGroup.SelectedObject;

            if selectedButton == app.QuenchBotButton
                % QuenchBot (Excalibot) selected
                updateSliders(app, app.robot_excalibot);  % Update sliders based on Excalibot's position
                app.CurrentRobot = app.robot_excalibot;  % Store the selected robot
                updateLights(app);
            elseif selectedButton == app.EvaluateBotButton
                % EvaluateBot (UR5) selected
                updateSliders(app, app.robot_UR);  % Update sliders based on UR5's position
                app.CurrentRobot = app.robot_UR;  % Store the selected robot
                updateLights(app);
            end

        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: USlider
        function USliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: VSlider
        function VSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: WSlider
        function WSliderValueChanged(app, event)
            moveRobotBasedOnSliders(app, app.CurrentRobot);
        end

        % Value changed function: EmergencyButton
        function EmergencyButtonValueChanged(app, event)
            updateLights(app);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;  % Get the key that was pressed

            % Check if the pressed key is 'space'
            if strcmp(key, 'space')
                app.EmergencyButton.Value = ~app.EmergencyButton.Value;
                updateLights(app);
            end
            
        end

        % Selection changed function: ProgramSelectButtonGroup
        function ProgramSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProgramSelectButtonGroup.SelectedObject;

        end

        % Button pushed function: StartProgramButton
        function StartProgramButtonPushed(app, event)

        end

        % Button pushed function: PauseProgramButton
        function PauseProgramButtonPushed(app, event)

        end

        % Button pushed function: StopProgramButton
        function StopProgramButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-5 5];
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.AxisControlButtonGroup = uibuttongroup(app.UIFigure);
            app.AxisControlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxisControlButtonGroupSelectionChanged, true);
            app.AxisControlButtonGroup.Title = 'AxisControl';
            app.QuenchBotButton = uiradiobutton(app.AxisControlButtonGroup);
            app.QuenchBotButton.Text = 'QuenchBot';
            app.QuenchBotButton.Value = true;
            app.EvaluateBotButton = uiradiobutton(app.AxisControlButtonGroup);
            app.EvaluateBotButton.Text = 'EvaluateBot';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Stopped';
            app.StoppedLamp = uilamp(app.UIFigure);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [-5 5];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-5 5];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ProgramSelectButtonGroup = uibuttongroup(app.UIFigure);
            app.ProgramSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProgramSelectButtonGroupSelectionChanged, true);
            app.ProgramSelectButtonGroup.Title = 'ProgramSelect';
            app.MainButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.MainButton.Text = 'Main';
            app.MainButton.Value = true;
            app.PickupButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.PickupButton.Text = 'Pickup';
            app.QuenchButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.QuenchButton.Text = 'Quench';
            app.EvaluateButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.EvaluateButton.Text = 'Evaluate';
            app.DisposeButton = uitogglebutton(app.ProgramSelectButtonGroup);
            app.DisposeButton.Text = 'Dispose';
            app.IdleLampLabel = uilabel(app.UIFigure);
            app.IdleLampLabel.Text = 'Idle';
            app.IdleLamp = uilamp(app.UIFigure);
            app.USliderLabel = uilabel(app.UIFigure);
            app.USliderLabel.Text = 'U';
            app.USlider = uislider(app.UIFigure);
            app.USlider.Limits = [-180 180];
            app.USlider.ValueChangedFcn = createCallbackFcn(app, @USliderValueChanged, true);
            app.OperatingLampLabel = uilabel(app.UIFigure);
            app.OperatingLampLabel.Text = 'Operating';
            app.OperatingLamp = uilamp(app.UIFigure);
            app.VSliderLabel = uilabel(app.UIFigure);
            app.VSliderLabel.Text = 'V';
            app.VSlider = uislider(app.UIFigure);
            app.VSlider.Limits = [-180 180];
            app.VSlider.ValueChangedFcn = createCallbackFcn(app, @VSliderValueChanged, true);
            app.StartProgramButton = uibutton(app.UIFigure, 'push');
            app.StartProgramButton.ButtonPushedFcn = createCallbackFcn(app, @StartProgramButtonPushed, true);
            app.StartProgramButton.Text = 'StartProgram';
            app.MovementSpeedQuenchBotGauge = uigauge(app.UIFigure, 'linear');
            app.WSliderLabel = uilabel(app.UIFigure);
            app.WSliderLabel.Text = 'W';
            app.WSlider = uislider(app.UIFigure);
            app.WSlider.Limits = [-180 180];
            app.WSlider.ValueChangedFcn = createCallbackFcn(app, @WSliderValueChanged, true);
            app.EmergencyButton = uibutton(app.UIFigure, 'state');
            app.EmergencyButton.ValueChangedFcn = createCallbackFcn(app, @EmergencyButtonValueChanged, true);
            app.EmergencyButton.Icon = fullfile(pathToMLAPP, 'emergency-stop-warning-sign-s-1182.png');
            app.EmergencyButton.Text = '';
            app.IgnitionQBotSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IgnitionQBotSwitch.ValueChangedFcn = createCallbackFcn(app, @IgnitionQBotSwitchValueChanged, true);
            app.IgnitionEbotSwitch = uiswitch(app.UIFigure, 'toggle');
            app.IgnitionEbotSwitch.ValueChangedFcn = createCallbackFcn(app, @IgnitionEbotSwitchValueChanged, true);
            app.PauseProgramButton = uibutton(app.UIFigure, 'push');
            app.PauseProgramButton.ButtonPushedFcn = createCallbackFcn(app, @PauseProgramButtonPushed, true);
            app.PauseProgramButton.Text = 'PauseProgram';
            app.MovementSpeedQuenchBotGaugeLabel = uilabel(app.UIFigure);
            app.MovementSpeedQuenchBotGaugeLabel.Text = 'MovementSpeedQuenchBot';
            app.MovementSpeedEvaluateBotGauge = uigauge(app.UIFigure, 'linear');
            app.StopProgramButton = uibutton(app.UIFigure, 'push');
            app.StopProgramButton.ButtonPushedFcn = createCallbackFcn(app, @StopProgramButtonPushed, true);
            app.StopProgramButton.Text = 'StopProgram';
            app.IgnitionQBotSwitchLabel = uilabel(app.UIFigure);
            app.IgnitionQBotSwitchLabel.Text = 'IgnitionQBot';
            app.IgnitionEbotSwitchLabel = uilabel(app.UIFigure);
            app.IgnitionEbotSwitchLabel.Text = 'IgnitionEbot';
            app.MovementSpeedEvaluateBotGaugeLabel = uilabel(app.UIFigure);
            app.MovementSpeedEvaluateBotGaugeLabel.Text = 'MovementSpeedEvaluateBot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end